classdef VirtualReality < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AugmentedRealityUIFigure  matlab.ui.Figure
        Panel                     matlab.ui.container.Panel
        CameraDropDownLabel       matlab.ui.control.Label
        CameraDropDown            matlab.ui.control.DropDown
        UIAxes                    matlab.ui.control.UIAxes
    end


    properties (Access = private)
        camera % The webcam used in the image
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            webcams = webcamlist;
            
            app.CameraDropDown.Items = webcams;
            
            value = app.CameraDropDown.Value;
            
            app.camera = webcam(value);
            
            vidRes = strsplit(app.camera.Resolution, 'x'); 
            disp(vidRes)
            ARImage = image(app.UIAxes, zeros(str2double(vidRes{2}), str2double(vidRes{1})) ); 
            preview(app.camera, ARImage);
        end

        % Value changed function: CameraDropDown
        function CameraChanged(app, event)
            value = app.CameraDropDown.Value;
            
            app.camera = webcam(value);
            
            vidRes = app.camera.Resolution; 
            
            ARImage = image(app.UIAxes, zeros(vidRes(2), vidRes(1)) ); 
            preview(app.camera, ARImage); 
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.AugmentedRealityUIFigure = uifigure;
            app.AugmentedRealityUIFigure.Name = 'Augmented Reality';
            setAutoResize(app, app.AugmentedRealityUIFigure, true)
            app.Panel = uipanel(app.AugmentedRealityUIFigure);
            app.CameraDropDownLabel = uilabel(app.Panel);
            app.CameraDropDownLabel.Text = 'Camera';
            app.CameraDropDown = uidropdown(app.Panel);
            app.CameraDropDown.Items = {'Option 1', 'Option 2'};
            app.CameraDropDown.ValueChangedFcn = createCallbackFcn(app, @CameraChanged, true);
            app.UIAxes = uiaxes(app.Panel);
        end
    end

    methods (Access = public)

        % Construct app
        function app = VirtualReality()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.AugmentedRealityUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AugmentedRealityUIFigure)
        end
    end
end